classdef LPC_Vocoder_Interim_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        AnalysisPanel                matlab.ui.container.Panel
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        InputPanel                   matlab.ui.container.Panel
        RecordnowButtonGroup         matlab.ui.container.ButtonGroup
        RecordAudioButton            matlab.ui.control.Button
        PlaySynthesizedAudioButton   matlab.ui.control.Button
        DurationsTextAreaLabel       matlab.ui.control.Label
        DurationsTextArea            matlab.ui.control.TextArea
        SynthesizeAudioButton        matlab.ui.control.Button
        ORLabel                      matlab.ui.control.Label
        PrerecordedButtonGroup       matlab.ui.container.ButtonGroup
        SynthesizeAudioButton_2      matlab.ui.control.Button
        PlaySynthesizedAudiioButton  matlab.ui.control.Button
        LPCParametersPanel           matlab.ui.container.Panel
        FrameWidthmsLabel            matlab.ui.control.Label
        FrameWidthmsEditField        matlab.ui.control.NumericEditField
        FrameOffsetmsLabel           matlab.ui.control.Label
        FrameOffsetmsEditField       matlab.ui.control.NumericEditField
        LPCOrderEditFieldLabel       matlab.ui.control.Label
        LPCOrderEditField            matlab.ui.control.NumericEditField
        PitchDetectionButtonGroup    matlab.ui.container.ButtonGroup
        CepstrumButton               matlab.ui.control.RadioButton
        AutocorrelationButton        matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        OriginalAudio      % recorded audio vector
        OriginalAudioFS    % recorded audio vector sampling frequency
        SynthesizedAudio   % lpc-synthesized audio vector
        SynthesizedAudioFS % lpc-synthesized audio vector
    end
    

    methods (Access = private)

        % Button pushed function: RecordAudioButton
        function RecordAudioButtonPushed(app, event)

            Fs=16000;
            ID=0;
            nbits=16;
            audioObject = audiorecorder(Fs,nbits,1,ID);     
            duration = str2double(app.DurationsTextArea.Value{1});
            msgbox('Recording');
            recordblocking(audioObject, duration);
            msgbox('Done');   
            y = getaudiodata(audioObject);
            %plot(app.UIAxes2, spectrogram(y));          
%             i=strfind(file,'.');
%             filename=file(1:i-1);
%             filetype=file(i:end);
%             filename_new=strcat(filename,'_synth_',int2str(Fsd),'.wav');
            audiowrite('recorded_audio.wav',y,Fs);
            app.OriginalAudio=y;
            app.OriginalAudioFS=Fs;
     
        end

        % Button pushed function: PlaySynthesizedAudioButton
        function PlaySynthesizedAudioButtonPushed(app, event)
            
              sound(app.SynthesizedAudio,app.SynthesizedAudioFS);
         
        end

        % Callback function
        function SwitchValueChanged(app, event)
            
            audioObject = evalin('base', 'audioObject');
            y = getaudiodata(audioObject);
            %Fs = audioObject.SampleRate;
            value = app.PitchShiftSwitch.Value;
            if(value)
                plot(app.UIAxes2, spectrogram(y));
            else
                x = lpc(y);
                plot(app.UIAxes2, spectrogram(x));
            end
                
        end

        % Callback function
        function UpdateButtonValueChanged(app, event)
                                                                
        end

        % Callback function
        function UpdateButtonPushed(app, event)
         
        end

        % Button pushed function: SynthesizeAudioButton_2
        function SynthesizeAudioButton_2Pushed(app, event)
            
            %[FileName, PathName] = uigetfile({'*.wav; *.aif'}, 'Select audio file');
            FileName = 'bernie_mike_14700.wav';
            
            lpc_ord=app.LPCOrderEditField.Value; % order of all-pole synthesis filter
            window=1; % hamming window
            L_frame=app.FrameWidthmsEditField.Value; % frame width in range of 1-100ms
            R_frame=app.FrameOffsetmsEditField.Value; % frame offset in range of 1-100ms
            over_frame=1; % # overlapping frames
            detect_method=app.AutocorrelationButton.Value; % 1=autocorrelation, 0=cepstrum. Radio buttons are either on or off, since there are two, we look only at autocor button
            pitch_shift=0;
            %             switch app.PitchShiftSwitch.Value
%                             case 'Male'
%                                 pitch_shift=0;
%                             case 'Female'
%                                 pitch_shift=1;
%             end
%             pitch_shift
            [sout,fs]=lpc_wrapper(FileName,lpc_ord,window,L_frame,R_frame,over_frame,detect_method,pitch_shift);
            app.SynthesizedAudio = sout;
            app.SynthesizedAudioFS = fs;
            
            [y, Fs] = audioread(FileName);
            plot(app.UIAxes, (1/Fs)*(0:length(y)-1), y);
            %plot(app.UIAxes2, spectrogram(y));
            %assignin('base', 'audioObject', audioObject);
            
            %disp(y);
            
            plot(app.UIAxes_2, (1/fs)*(0:length(sout)-1), sout);
            
                [s,f,t]=spectrogram(y(:,1),hamming(240),120,Fs);
                mags=s.*conj(s);
                image(app.UIAxes2,f, t, -10.*log10(mags/max(max(mags))));
                set(app.UIAxes2, 'YDir','normal');
                ylim(app.UIAxes2,[t(1) t(end)]);
                xlim(app.UIAxes2,[f(1) f(end)]);
                %colorbar(app.UIAxes2);
                
%                 reconstructed = sout;
                [s,f,t]=spectrogram(sout,hamming(240),120,16000);
                
                mags=real(s.*conj(s));
                %disp(mags);
                image(app.UIAxes3,f, t, -10.*log10(mags/max(max(mags))));
                set(app.UIAxes3, 'YDir','normal');
                ylim(app.UIAxes3,[t(1) t(end)]);
                xlim(app.UIAxes3,[f(1) f(end)]);


        end

        % Button pushed function: PlaySynthesizedAudiioButton
        function PlaySynthesizedAudiioButtonPushed(app, event)
            
%             global sout
%             sound(sout, 16000);
            sound(app.SynthesizedAudio,app.SynthesizedAudioFS);
            
        end

        % Button pushed function: SynthesizeAudioButton
        function SynthesizeAudioButtonPushed(app, event)
           
             %[FileName, PathName] = uigetfile({'*.wav; *.aif'}, 'Select audio file');
            FileName = 'recorded_audio.wav';
            
            lpc_ord=app.LPCOrderEditField.Value; % order of all-pole synthesis filter
            window=1; % hamming window
            L_frame=app.FrameWidthmsEditField.Value; % frame width in range of 1-100ms
            R_frame=app.FrameOffsetmsEditField.Value; % frame offset in range of 1-100ms
            over_frame=1; % # overlapping frames
            detect_method=app.AutocorrelationButton.Value; % 1=autocorrelation, 0=cepstrum. Radio buttons are either on or off, since there are two, we look only at autocor button
%             switch app.PitchShiftSwitch.Value
%                 case 'Male'
%                     pitch_shift=0;
%                 case 'Female'
%                     pitch_shift=1;
%             end
            pitch_shift=0;
            [sout,fs]=lpc_wrapper(FileName,lpc_ord,window,L_frame,R_frame,over_frame,detect_method,pitch_shift);
            app.SynthesizedAudio = sout;
            app.SynthesizedAudioFS = fs;

            %Fs = audioObject.SampleRate;
%             global sout2;

%             duration = str2double(app.DurationsTextArea.Value{1});
    
            %y = getaudiodata(audioObject);
            [y, Fs] = audioread(FileName);
            plot(app.UIAxes, (1/Fs)*(0:length(y)-1), y);
            %plot(app.UIAxes2, spectrogram(y));
            %assignin('base', 'audioObject', audioObject);
            
            %disp(y);
            
            %assignin('base', 'audioObject', audioObject);
            
                [s,f,t]=spectrogram(y,hamming(240),120,16000);
                mags=real(s.*conj(s));
                image(app.UIAxes2,f, t, -10.*log10(mags/max(max(mags))));
                set(app.UIAxes2, 'YDir','normal');
                ylim(app.UIAxes2,[t(1) t(end)]);
                xlim(app.UIAxes2,[f(1) f(end)]);
                %colorbar(app.UIAxes2);

                plot(app.UIAxes_2, (1/fs)*(0:length(sout)-1), sout);
%                 reconstructed = sout2;
                [s,f,t]=spectrogram(sout,hamming(240),120,16000);
                
                mags=real(s.*conj(s));
                %disp(mags);
                image(app.UIAxes3,f, t, -10.*log10(mags/max(max(mags))));
                set(app.UIAxes3, 'YDir','normal');
                ylim(app.UIAxes3,[t(1) t(end)]);
                xlim(app.UIAxes3,[f(1) f(end)]);
                
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.AnalysisPanel = uipanel(app.UIFigure);
            app.AnalysisPanel.Title = 'Analysis';
            app.UIAxes2 = uiaxes(app.AnalysisPanel);
            title(app.UIAxes2, 'Original audio spectrogram')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes2.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes3 = uiaxes(app.AnalysisPanel);
            title(app.UIAxes3, 'Reconstructed audio spectrogram')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes = uiaxes(app.AnalysisPanel);
            title(app.UIAxes, 'Recorded Audio Signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.AnalysisPanel);
            title(app.UIAxes_2, 'Reconstructed Audio Signal')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input';
            app.RecordnowButtonGroup = uibuttongroup(app.InputPanel);
            app.RecordnowButtonGroup.Title = 'Record now';
            app.RecordAudioButton = uibutton(app.RecordnowButtonGroup, 'push');
            app.RecordAudioButton.ButtonPushedFcn = createCallbackFcn(app, @RecordAudioButtonPushed, true);
            app.RecordAudioButton.Text = 'Record Audio';
            app.PlaySynthesizedAudioButton = uibutton(app.RecordnowButtonGroup, 'push');
            app.PlaySynthesizedAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySynthesizedAudioButtonPushed, true);
            app.PlaySynthesizedAudioButton.Text = 'Play Synthesized Audio';
            app.DurationsTextAreaLabel = uilabel(app.RecordnowButtonGroup);
            app.DurationsTextAreaLabel.Text = 'Duration (s)';
            app.DurationsTextArea = uitextarea(app.RecordnowButtonGroup);
            app.DurationsTextArea.Value = {'4'};
            app.SynthesizeAudioButton = uibutton(app.RecordnowButtonGroup, 'push');
            app.SynthesizeAudioButton.ButtonPushedFcn = createCallbackFcn(app, @SynthesizeAudioButtonPushed, true);
            app.SynthesizeAudioButton.Text = 'Synthesize Audio';
            app.ORLabel = uilabel(app.InputPanel);
            app.ORLabel.Text = 'OR';
            app.PrerecordedButtonGroup = uibuttongroup(app.InputPanel);
            app.PrerecordedButtonGroup.Title = 'Pre-recorded';
            app.SynthesizeAudioButton_2 = uibutton(app.PrerecordedButtonGroup, 'push');
            app.SynthesizeAudioButton_2.ButtonPushedFcn = createCallbackFcn(app, @SynthesizeAudioButton_2Pushed, true);
            app.SynthesizeAudioButton_2.Text = 'Synthesize Audio';
            app.PlaySynthesizedAudiioButton = uibutton(app.PrerecordedButtonGroup, 'push');
            app.PlaySynthesizedAudiioButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySynthesizedAudiioButtonPushed, true);
            app.PlaySynthesizedAudiioButton.Text = 'Play Synthesized Audiio';
            app.LPCParametersPanel = uipanel(app.UIFigure);
            app.LPCParametersPanel.Title = 'LPC Parameters';
            app.FrameWidthmsLabel = uilabel(app.LPCParametersPanel);
            app.FrameWidthmsLabel.Text = 'Frame Width (ms)';
            app.FrameWidthmsEditField = uieditfield(app.LPCParametersPanel, 'numeric');
            app.FrameWidthmsEditField.Value = 60;
            app.FrameOffsetmsLabel = uilabel(app.LPCParametersPanel);
            app.FrameOffsetmsLabel.Text = 'Frame Offset (ms)';
            app.FrameOffsetmsEditField = uieditfield(app.LPCParametersPanel, 'numeric');
            app.FrameOffsetmsEditField.Value = 20;
            app.LPCOrderEditFieldLabel = uilabel(app.LPCParametersPanel);
            app.LPCOrderEditFieldLabel.Text = 'LPC Order';
            app.LPCOrderEditField = uieditfield(app.LPCParametersPanel, 'numeric');
            app.LPCOrderEditField.Value = 20;
            app.PitchDetectionButtonGroup = uibuttongroup(app.UIFigure);
            app.PitchDetectionButtonGroup.Title = 'Pitch Detection';
            app.CepstrumButton = uiradiobutton(app.PitchDetectionButtonGroup);
            app.CepstrumButton.Text = 'Cepstrum';
            app.CepstrumButton.Value = true;
            app.AutocorrelationButton = uiradiobutton(app.PitchDetectionButtonGroup);
            app.AutocorrelationButton.Text = 'Autocorrelation';
        end
    end

    methods (Access = public)

        % Construct app
        function app = LPC_Vocoder_Interim_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end